classdef Comparespectra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Coimpare2typeEditField       matlab.ui.control.EditField
        Coimpare2typeEditFieldLabel  matlab.ui.control.Label
        Compare1typeEditField        matlab.ui.control.EditField
        Compare1typeEditFieldLabel   matlab.ui.control.Label
        compare2CheckBox             matlab.ui.control.CheckBox
        compare1CheckBox             matlab.ui.control.CheckBox
        plotdataButton               matlab.ui.control.Button
        loadcoregButton              matlab.ui.control.Button
        getdatapointsButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        x % Description
        y % Description
        sqa % Description
        mqa % Description
        r % Description
        c % Description
        xqa % Description
        yqa % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadcoregButton
        function loadcoregButtonPushed(app, event)
        %string file_name 
            [file,path] = uigetfile('*.mat')
           
                if strcmp(file ,'NT158.mat') == 1
                    load('NT158.mat', 'a')
                    imshow(a)
                elseif strcmp(file,'NT177.mat') == 1
                    load('NT177.mat','b')
                    imshow(b)
                elseif strcmp(file,'NT187.mat') == 1
                    load('NT187.mat','c')
                    imshow(c)
                elseif strcmp(file,'NT176.mat') == 1
                    load('NT176.mat','l')
                    imshow(l)
                elseif strcmp(file,'T1158.mat') == 1
                    load('T1158.mat','e')
                    imshow(e)
                elseif strcmp(file,'T1176.mat') == 1
                    load('T1176.mat','f')
                    imshow(f)
                elseif strcmp(file,'T1177.mat') == 1
                    load('T1177.mat','g')
                    imshow(g)
                elseif strcmp(file,'T1187.mat') == 1
                    load('T1187.mat','h')
                    imshow(h)
                elseif strcmp(file,'T2158.mat') == 1
                    load('T2158.mat','i')
                    imshow(i)
                elseif strcmp(file,'T2177.mat') == 1
                    load('T2177.mat','j')
                    imshow(j)
                elseif strcmp(file,'T2187.mat') == 1
                    load('T2187.mat','k')
                    imshow(k)
                elseif strcmp(file,'T2176.mat') == 1
                    load('T2176.mat','m')
                    imshow(m)
                elseif strcmp(file,'gbmt_crossed_mask.mat') == 1
                    load('gbmt_crossed_mask.mat','gbmt')
                    imshow(gbmt)
                elseif strcmp(file,'normal_mask.mat') == 1
                    load('normal_mask.mat','normal')
                    imshow(normal)
                end
        end

        % Button pushed function: getdatapointsButton
        function getdatapointsButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            load(file, 'reconstructedData')
            %random = drawpolygon;
            %Amask = createMask(random);
            %figure(2); imshow(Amask)
            
            
                [app.x app.y] = getpts()
                app.x = floor(app.x)
                app.y = floor(app.y)
                %find(Amask == 1)
                L = length(app.x)
                d = []
                for k1 = 1:L
                    d = cat(2, d, squeeze(reconstructedData(app.y(k1),app.x(k1),:)))
                end
            if app.compare1CheckBox.Value == 1
                app.xqa = transpose(d)
                assignin('base','xqa',app.xqa);
            end
            if app.compare2CheckBox.Value == 1
                app.yqa = transpose(d)
                assignin('base','yqa',app.yqa);
            end    
        end

        % Button pushed function: plotdataButton
        function plotdataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            load(file, 'qvals')
                
            app.sqa = mean(app.xqa,1)
            errred = std(app.xqa)
            figure(3); e2 = errorbar(qvals, app.sqa, errred); 
            e2.Color = 'r'
            hold on;
            L = size(app.xqa,1)

            app.mqa = mean(app.yqa,1)
            errblack = std(app.yqa)
            figure(3); e2 = errorbar(qvals, app.mqa, errblack); 
            e2.Color = 'k'
            
            legend(app.Compare1typeEditField.Value, app.Coimpare2typeEditField.Value);
            figure(3); xlabel('q [1/A]'); ylabel('XRD amplitude [arb]'); title(["Spectra Line Graph with Error Bars with" num2str(L) "points"])

            %igure; plot(app.sqa,app.mqa)
            %xlabel(app.Compare1typeEditField.Value); ylabel(app.Coimpare2typeEditField.Value); title("T2176")


        end

        % Value changed function: Compare1typeEditField
        function Compare1typeEditFieldValueChanged(app, event)
            value = app.Compare1typeEditField.Value;
            %value = input('your name');
        end

        % Value changed function: Coimpare2typeEditField
        function Coimpare2typeEditFieldValueChanged(app, event)
            value = app.Coimpare2typeEditField.Value;
            %value = input('your name');
        end

        % Value changed function: compare1CheckBox
        function compare1CheckBoxValueChanged(app, event)
            value = app.compare1CheckBox.Value;
            
        end

        % Value changed function: compare2CheckBox
        function compare2CheckBoxValueChanged(app, event)
            value = app.compare2CheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.getdatapointsButton = uibutton(app.UIFigure, 'push');
            app.getdatapointsButton.ButtonPushedFcn = createCallbackFcn(app, @getdatapointsButtonPushed, true);
            app.getdatapointsButton.Text = 'get data points';
            app.loadcoregButton = uibutton(app.UIFigure, 'push');
            app.loadcoregButton.ButtonPushedFcn = createCallbackFcn(app, @loadcoregButtonPushed, true);
            app.loadcoregButton.Text = 'loadcoreg';
            app.plotdataButton = uibutton(app.UIFigure, 'push');
            app.plotdataButton.ButtonPushedFcn = createCallbackFcn(app, @plotdataButtonPushed, true);
            app.plotdataButton.Text = 'plot data';
            app.compare1CheckBox = uicheckbox(app.UIFigure);
            app.compare1CheckBox.ValueChangedFcn = createCallbackFcn(app, @compare1CheckBoxValueChanged, true);
            app.compare1CheckBox.Text = 'compare 1';
            app.compare2CheckBox = uicheckbox(app.UIFigure);
            app.compare2CheckBox.ValueChangedFcn = createCallbackFcn(app, @compare2CheckBoxValueChanged, true);
            app.compare2CheckBox.Text = 'compare 2';
            app.Compare1typeEditFieldLabel = uilabel(app.UIFigure);
            app.Compare1typeEditFieldLabel.Text = 'Compare 1 type';
            app.Compare1typeEditField = uieditfield(app.UIFigure, 'text');
            app.Compare1typeEditField.ValueChangedFcn = createCallbackFcn(app, @Compare1typeEditFieldValueChanged, true);
            app.Coimpare2typeEditFieldLabel = uilabel(app.UIFigure);
            app.Coimpare2typeEditFieldLabel.Text = 'Coimpare 2 type';
            app.Coimpare2typeEditField = uieditfield(app.UIFigure, 'text');
            app.Coimpare2typeEditField.ValueChangedFcn = createCallbackFcn(app, @Coimpare2typeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Comparespectra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end