classdef app5connect2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField         matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        importumapButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        reduction % reduction
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importumapButton
        function importumapButtonPushed(app, event)
            import umapFileExchange.*;
            load('recon.mat')
            dataspec = reshape(reconstructedData, 146*49, [])
            [app.reduction] = run_umap(dataspec)
            figure; plot(app.reduction(:,1), app.reduction(:,2),'.')
            hold on;
            set(app.importumapButton, 'Enable', 'off')
            set(app.loaddataButton, 'Text', 'umap loaded ...')

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
             value = app.EditField.Value;
             plot(app.reduction(value,1), app.reduction(value,1), 'rx')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.importumapButton = uibutton(app.UIFigure, 'push');
            app.importumapButton.ButtonPushedFcn = createCallbackFcn(app, @importumapButtonPushed, true);
            app.importumapButton.Text = 'import umap';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5connect2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end