classdef app6connect2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        YvaluesEditField       matlab.ui.control.NumericEditField
        YvaluesEditFieldLabel  matlab.ui.control.Label
        importumapButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        reduction % reduction
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importumapButton
        function importumapButtonPushed(app, event)
            import umapFileExchange.*;
            load('recon.mat')
            dataspec = reshape(reconstructedDataCopy, 146*49, [])
            [app.reduction] = run_umap(dataspec)
            figure; plot(app.reduction(:,1), app.reduction(:,2),'.')
            hold on;
            set(app.importumapButton, 'Enable', 'off')
            set(app.importumapButton, 'Text', 'umap loaded ...')
        end

        % Value changed function: YvaluesEditField
        function YvaluesEditFieldValueChanged(app, event)
            value = app.YvaluesEditField.Value;
            plot(app.reduction(value,1), app.reduction(value,2), 'rx')
            hold on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.importumapButton = uibutton(app.UIFigure, 'push');
            app.importumapButton.ButtonPushedFcn = createCallbackFcn(app, @importumapButtonPushed, true);
            app.importumapButton.Text = 'import umap';
            app.YvaluesEditFieldLabel = uilabel(app.UIFigure);
            app.YvaluesEditFieldLabel.Text = 'Y-values';
            app.YvaluesEditField = uieditfield(app.UIFigure, 'numeric');
            app.YvaluesEditField.ValueChangedFcn = createCallbackFcn(app, @YvaluesEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6connect2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end