classdef appconnect_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        datapickerButton     matlab.ui.control.Button
        GreyMatterCheckBox   matlab.ui.control.CheckBox
        NoCancerCheckBox     matlab.ui.control.CheckBox
        WhiteMatterCheckBox  matlab.ui.control.CheckBox
        CancerCheckBox       matlab.ui.control.CheckBox
        importumapButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        reduction % Description
        cancerindex % Description
        color % Description
        dimension % Description
        n         % Description
        x % Description
        y % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importumapButton
        function importumapButtonPushed(app, event)
            import umapFileExchange.*;
            
            % string file_name 
            [file,path] = uigetfile('*.mat')
            file_name = file

            if strcmp(file_name ,'reconGBMT.mat') == 1
                load('reconGBMT.mat', 'reconstructedData2')
                dataspec = reshape(reconstructedData2, 146*72, [])
            elseif strcmp(file_name,'reconNormal.mat') == 1
                load('reconNormal.mat', 'reconstructedData3')
                dataspec = reshape(reconstructedData3, 146*63, [])
            elseif strcmp(file_name,'recon.mat') == 1 
                load('recon.mat','reconstructedData1')
                dataspec = reshape(reconstructedData1, 146*49, [])
            end

            [app.reduction] = run_umap(dataspec)
            figure; plot(app.reduction(:,1), app.reduction(:,2),'.')
            hold on;
            set(app.importumapButton, 'Enable', 'off')
            set(app.importumapButton, 'Text', 'umap loaded ...')
        end

        % Value changed function: CancerCheckBox
        function CancerCheckBoxValueChanged(app, event)
            app.color = app.CancerCheckBox.Value;
            if app.CancerCheckBox.Value == 1
                app.color = 'rx'
            elseif app.CancerCheckBox.Value == 0
                set(app.CancerCheckBox, 'Enable', 'off')
                set(app.CancerCheckBox, 'Enable', 'on')
            end 
        end

        % Value changed function: WhiteMatterCheckBox
        function WhiteMatterCheckBoxValueChanged(app, event)
            app.color = app.WhiteMatterCheckBox.Value;
            if app.WhiteMatterCheckBox.Value == 1
               app.color = 'yx'
            elseif app.WhiteMatterCheckBox == 0
                set(app.WhiteMatterCheckBox, 'Enable', 'off')
                set(app.WhiteMatterCheckBox, 'Enable', 'on')
            end 
        end

        % Value changed function: NoCancerCheckBox
        function NoCancerCheckBoxValueChanged(app, event)
            app.color = app.NoCancerCheckBox.Value;
            if app.NoCancerCheckBox.Value == 1
                app.color = 'gx'
            elseif app.NoCancerCheckBox.Value == 0
                set(app.NoCancerCheckBox, 'Enable', 'off')
                set(app.NoCancerCheckBox, 'Enable', 'on')  
            end 
        end

        % Value changed function: GreyMatterCheckBox
        function GreyMatterCheckBoxValueChanged(app, event)
            app.color = app.GreyMatterCheckBox.Value;
            if app.GreyMatterCheckBox.Value == 1
                app.color = 'kx'
            elseif app.GreyMatterCheckBox.Value == 0
                set(app.GreyMatterCheckBox, 'Enable', 'off')
                set(app.GreyMatterCheckBox, 'Enable', 'on')
            end 
        end

        % Button pushed function: datapickerButton
        function datapickerButtonPushed(app, event)
                app.n = 150
                [r,c] = ginput(app.n)
                app.x = round(r)
                app.y = round(c)
                plot(app.reduction(app.y,1), app.reduction(app.y,2), app.color) % plots double onto UMAP reduction 
                hold on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.importumapButton = uibutton(app.UIFigure, 'push');
            app.importumapButton.ButtonPushedFcn = createCallbackFcn(app, @importumapButtonPushed, true);
            app.importumapButton.Text = 'import umap';
            app.CancerCheckBox = uicheckbox(app.UIFigure);
            app.CancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @CancerCheckBoxValueChanged, true);
            app.CancerCheckBox.Text = 'Cancer';
            app.WhiteMatterCheckBox = uicheckbox(app.UIFigure);
            app.WhiteMatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhiteMatterCheckBoxValueChanged, true);
            app.WhiteMatterCheckBox.Text = 'White Matter';
            app.NoCancerCheckBox = uicheckbox(app.UIFigure);
            app.NoCancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoCancerCheckBoxValueChanged, true);
            app.NoCancerCheckBox.Text = 'No Cancer';
            app.GreyMatterCheckBox = uicheckbox(app.UIFigure);
            app.GreyMatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreyMatterCheckBoxValueChanged, true);
            app.GreyMatterCheckBox.Text = 'Grey Matter';
            app.datapickerButton = uibutton(app.UIFigure, 'push');
            app.datapickerButton.ButtonPushedFcn = createCallbackFcn(app, @datapickerButtonPushed, true);
            app.datapickerButton.Text = 'datapicker';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appconnect_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end