classdef appfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OpenGUI2Button       matlab.ui.control.Button
        TQC_BrainsButton     matlab.ui.control.Button
        SuperResReconButton  matlab.ui.control.Button
        loaddataButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        filename % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            set(app.loaddataButton,'Enable', 'on')
            set(app.SuperResReconButton, 'Enable', 'off')
            set(app.TQC_BrainsButton, 'Enable', 'off')
            %%load('ReconResults_Model_3_BrainScan_GBMT_FullFanExtent_400iter_M3.mat')
            [file,path] = uigetfile('*.mat')  %open a mat file
            app.filename = file;
            set(app.loaddataButton, 'Text', 'data loaded...')
            set(app.SuperResReconButton, 'Enable', 'on')
            set(app.loaddataButton, 'Enable', 'off')
        end

        % Button pushed function: SuperResReconButton
        function SuperResReconButtonPushed(app, event)
                   %% Script for Super-Resolutioning Reconstructions
            
            % set to folder containing all cases
            files = dir(app.filename);
            files = struct2cell(files);
            files = files(1,:)';
            
            for iter = 1 : length(files)
            
                load(files{iter})
                r = reconstructedData;
                dim = size(r);
            
                % making super resolution
                rS = zeros(dim(1)*3,dim(2)*3,dim(3));
                dimS = size(rS);
                for i = 1 : size(rS,1)
                    for j = 1 : size(rS,2)
            
                        % dealing with boundary pixels
                        if i == 1
                            rS(i,j,:) = r(ceil(i/3),ceil(j/3),:);
                            continue
                        elseif i == size(rS,1)
                            rS(i,j,:) = r(ceil(i/3),ceil(j/3),:);
                            continue
                        elseif j == 1
                            rS(i,j,:) = r(ceil(i/3),ceil(j/3),:);
                            continue
                        elseif j == size(rS,2)
                            rS(i,j,:) = r(ceil(i/3),ceil(j/3),:);
                            continue
                        end
            
                        % interpolating all other pixels spectra
                        % UL
                        if ismember(i,1:3:(size(rS,1)-2)) && ismember(j,1:3:(size(rS,2)-2))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3)-1,ceil(j/3)-1,:);
                            continue  
                        % U
                        elseif ismember(i,1:3:(size(rS,1)-2)) && ismember(j,2:3:(size(rS,2)-1))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3)-1,ceil(j/3),:);
                            continue  
                        % UR
                        elseif ismember(i,1:3:(size(rS,1)-2)) && ismember(j,3:3:size(rS,2))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3)-1,ceil(j/3)+1,:);
                            continue 
                        % L
                        elseif ismember(i,2:3:(size(rS,1)-1)) && ismember(j,1:3:(size(rS,2)-2))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3),ceil(j/3)-1,:);
                            continue 
                        % C
                        elseif ismember(i,2:3:(size(rS,1)-1)) && ismember(j,2:3:(size(rS,2)-1)) % central pixels set to old pixel spectra
                            rS(i,j,:) = 3 .* r(ceil(i/3),ceil(j/3),:); % 3 just to make similar intensity to others if not normalizing
                            continue
                        % R
                        elseif ismember(i,2:3:(size(rS,1)-1)) && ismember(j,3:3:size(rS,2))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3),ceil(j/3)+1,:);
                            continue 
                        % DL
                        elseif ismember(i,3:3:size(rS,1)) && ismember(j,1:3:(size(rS,2)-2))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3)+1,ceil(j/3)-1,:);
                            continue 
                        % D
                        elseif ismember(i,3:3:size(rS,1)) && ismember(j,2:3:(size(rS,2)-1))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3)+1,ceil(j/3),:);
                            continue 
                        % DR
                        elseif ismember(i,3:3:size(rS,1)) && ismember(j,3:3:size(rS,2))
                            rS(i,j,:) = (2 .* r(ceil(i/3),ceil(j/3),:)) + r(ceil(i/3)+1,ceil(j/3)+1,:);
                            continue 
                        end    
                    end
                end
            
                % saving super-res
                reconstructedData = rS;
                save(files{iter},'qvals','reconstructedData','scatSliceLocations','transScan','transSliceLocations');
            
                disp(['Case ',num2str(iter),' finished.'])   
            end
            set(app.SuperResReconButton, 'Text', 'SuperResRecon completed...')
            set(app.TQC_BrainsButton, 'Enable', 'on')
            set(app.SuperResReconButton, 'Enable', 'off')
        end

        % Button pushed function: TQC_BrainsButton
        function TQC_BrainsButtonPushed(app, event)
                        % Create TQC Image
            % Created by Stefan Stryker
            
            % run in folder with single .mat file contianing image data of interest
            files = dir(app.filename);
            names = files.name; 
            
            %% User Selectable ParametersreconstructedDataCopy
            
            minQ = 0.05;              % where to trim spectra for mean q calculation (in 1/A)
            maxQ = 0.305;             % ||
            minVal = 0.163;           % what to window TQC image to  % CHANGE THIS WINDOWING OF minVal and maxVal FOR SHIFTING COLOR RANGE
            maxVal = 0.198;           % ||  
            tBP = 10;                 % transmission bottom percentile for display
            tTP = 100;               % transmission top percentile for display
            
            %% Open Reconstructed Data, create qvalue range
            
            load(names) 
            
            qvals = qvals'; % variable in data file
            
            % finding index of trim locations
            [~,minQInd] = min(abs(qvals-minQ));
            [~,maxQInd] = min(abs(qvals-maxQ));
            
            %% Compute Weighted Mean Q
            
            meanQMat = zeros(size(reconstructedData,1),size(reconstructedData,2));
            for i = 1 : size(reconstructedData,1)
                for j = 1 : size(reconstructedData,2)
            
                    spectra = squeeze(reconstructedData(i,j,minQInd:maxQInd));  % seeing if smooth improves stability
                    meanQMat(i,j) = sum((spectra .* qvals(minQInd:maxQInd))) ./ sum(spectra);         
                end
            end
            
            %% Crop Transmission Image
            
            startScat = scatSliceLocations(1);
            endScat = scatSliceLocations(end);
            
            % accounting for motor moving 0.74mm vs 1mm
            startScat = ((158*startScat+1541)-1582)/117;  % floor()
            endScat = ((158*endScat+1541)-1582)/117; % ceil()
            
            cutToRight = round((startScat - transSliceLocations(1)) * 16); % 16 pixels per fan was +1 in the parenethesis
            cutToLeft = round((transSliceLocations(end) - endScat) * 16) - 1;
            
            transCropped = transScan;
            transCropped(:,(end-cutToLeft):end) = [];
            transCropped(:,1:cutToRight) = [];
            
            transCropped(2861:end,:) = [];     % removing lower rows not used for recon
            
            detPixelsMapping = round((((1:2860) - 1430.5) .* (312/632.6)) .* (0.1*3) + (72.5*3)); % for super-res XRD images
            transCropped = mat2gray(transCropped,[prctile(transCropped(:),tBP),prctile(transCropped(:),tTP)]); % normalizing between 0 to 1
            scatWidth = size(transCropped,2)/size(meanQMat,2); % used for image to map color on to trans
            
            transMask = repmat(transCropped,1,1,3);
            
            %% Project Mean Q into Detector Space
            
            detMeanQMat = [];
            for i = 1 : size(meanQMat,1)    
                numRows = length(find(detPixelsMapping == i)); % pulling rows to give color to 
            
                detMeanQTemp = [];
                for j = 1 : size(meanQMat,2)
            
                    % calculate correct amount of pixels to pull for decimal amount
                    startCol = round(scatWidth * j - scatWidth + 1);
                    endCol = round(scatWidth * j);
            
                    detMeanQTemp = [detMeanQTemp, ones(numRows,length(startCol:endCol)).*meanQMat(i,j)];     
                end
            
                detMeanQMat = [detMeanQMat;detMeanQTemp];
            end
            
            %% Create tXRD Images Plot
            
            qGrid = minVal : ((maxVal-minVal)/(115)) : maxVal;
            
            rgb1 = [0 0.9 0.85];
            rgb2 = [1 1 0];
            rgb3 = [1 0.2 0];
            rgb = [interp1([1,size(qGrid,2)/2], [rgb1;rgb2], 1:size(qGrid,2)/2);...
                interp1([1,size(qGrid,2)/2], [rgb2;rgb3], 1:size(qGrid,2)/2)];
            
            [~,index] = min(abs(qGrid-detMeanQMat(:)),[],2);  
            rgbReconImg = rgb(index,:);
            rgbReconImg = reshape(rgbReconImg,[size(detMeanQMat,1),size(detMeanQMat,2),3]);
            rgbTransImg = rgbReconImg .* transMask; 
            
            figure()
            imshow(transMask)
            
            figure();
            imhandle = imshow(rgbTransImg);    % showing smoothed transmission image
            colormap(rgb)
            h = colorbar('Location','southoutside');
            h.YTick = [0 0.5 1];
            h.YTickLabel = {num2str(round(minVal,3)), num2str(round((minVal+maxVal)/2,3)), num2str(round(maxVal,3))};
            set(gca,'fontweight','bold')
            ylabel(h, 'Mean q  (1/Å)')
            set(h,'fontweight','bold','FontSize',20)
            brighten(0.3)
            set(app.loaddataButton, 'Text', 'TQC_brains completed...')
            set(app.TQC_BrainsButton, 'Enable', 'off')
        end

        % Button pushed function: OpenGUI2Button
        function OpenGUI2ButtonPushed(app, event)
            open appconnect_final.mlapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loaddataButton = uibutton(app.UIFigure, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.SuperResReconButton = uibutton(app.UIFigure, 'push');
            app.SuperResReconButton.ButtonPushedFcn = createCallbackFcn(app, @SuperResReconButtonPushed, true);
            app.SuperResReconButton.Text = 'SuperResRecon';
            app.TQC_BrainsButton = uibutton(app.UIFigure, 'push');
            app.TQC_BrainsButton.ButtonPushedFcn = createCallbackFcn(app, @TQC_BrainsButtonPushed, true);
            app.TQC_BrainsButton.Text = 'TQC_Brains';
            app.OpenGUI2Button = uibutton(app.UIFigure, 'push');
            app.OpenGUI2Button.ButtonPushedFcn = createCallbackFcn(app, @OpenGUI2ButtonPushed, true);
            app.OpenGUI2Button.Text = 'Open GUI 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end