classdef coregapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SpectraLineGraphCancervsNonCancerButton  matlab.ui.control.Button
        ClusteringCancervsNonCancerButton  matlab.ui.control.Button
        NoCancerCheckBox           matlab.ui.control.CheckBox
        GrayMatterCheckBox         matlab.ui.control.CheckBox
        WhiteMatterCheckBox        matlab.ui.control.CheckBox
        LoadCoregButton            matlab.ui.control.Button
        SpectraLineGraphWhiteVsGrayvsCancerButton  matlab.ui.control.Button
        CancerCheckBox             matlab.ui.control.CheckBox
        spatiallocationplotButton  matlab.ui.control.Button
        ClusteringWhitevsGrayvsCancerButton  matlab.ui.control.Button
        datapickerButton           matlab.ui.control.Button
        XRDdataButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        coordinates % Description
        dcm_obj % Description
        x % Description
        y % Description
        n % Description
        color % Description
        file_name % Description
        ncolor % Description
        Cancerarray % Description
        NonCancerarray % Description
        grayarray % Description
        whitearray % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: XRDdataButton
        function XRDdataButtonPushed(app, event)
            % string file_name 
            [file,path] = uigetfile('*.mat')
            app.file_name = file

            if strcmp(app.file_name ,'reconGBMT.mat') == 1
                load('reconGBMT.mat', 'reconstructedData2')
                figure(4); imagesc(squeeze(reconstructedData2(:, :, 17)))
            elseif strcmp(app.file_name,'reconNormal.mat') == 1
                load('reconNormal.mat', 'reconstructedData3')
                figure(4); imagesc(squeeze(reconstructedData3(:, :, 15)))
            elseif strcmp(app.file_name,'recon.mat') == 1 
                load('recon.mat','reconstructedData1')
                figure(4); imagesc(squeeze(reconstructedData1(:, :, 17)))
            end

        end

        % Button pushed function: datapickerButton
        function datapickerButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            app.file_name = file
            load(app.file_name, 'reconstructedData')
            
                random = drawfreehand;
                tmask = createMask(random);
                figure (2); imshow(tmask)
                [r c] = find(tmask == 1)

                
%                app.n = 150
%                [r,c] = ginput(app.n)
               app.x = r
               app.y = c
               L = length(r)
            % Creating Clusters
            sqa = []
            for k1 = 1:L
                sqa = cat(2, sqa, squeeze(reconstructedData(app.x(k1),app.y(k1),:)))
            end

            if app.CancerCheckBox.Value == 1
                app.Cancerarray = transpose(sqa)
            elseif app.NoCancerCheckBox.Value == 1
                app.NonCancerarray = transpose(sqa)
            elseif app.WhiteMatterCheckBox.Value == 1
                app.whitearray = transpose(sqa)
            elseif app.GrayMatterCheckBox.Value == 1
                app.grayarray = transpose(sqa)
            end
 
        end

        % Button pushed function: spatiallocationplotButton
        function spatiallocationplotButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            app.file_name = file
            load(app.file_name, 'reconstructedData')
            if app.CancerCheckBox.Value == 1
                figure(5); imagesc(squeeze(reconstructedData(:, :, 15)))
                hold on; figure(5); plot(app.y,app.x, app.color)
            elseif app.CancerCheckBox.Value == 0
                figure(11); imagesc(squeeze(reconstructedData(:, :, 15)))
                hold on; figure(5); plot(app.y,app.x,app.ncolor)
            end
        end

        % Button pushed function: ClusteringWhitevsGrayvsCancerButton
        function ClusteringWhitevsGrayvsCancerButtonPushed(app, event)
            % RUN UMAP
            import umapFileExchange.*;
            [reduction1] = run_umap(app.Cancerarray)
            [reduction4] = run_umap(app.whitearray)
            [reduction3] = run_umap(app.grayarray)

            figure(6); plot(reduction1(:,1), reduction1(:,2),'r.')
            hold on;
            figure(6); plot(reduction4(:,1), reduction4(:,2),'g.')
            hold on;
            figure(6); plot(reduction3(:,1), reduction3(:,2),'k.')
            legend('Cancer','White Matter', 'Gray Matter');
            title('Transpose')


        end

        % Value changed function: CancerCheckBox
        function CancerCheckBoxValueChanged(app, event)
            app.color = app.CancerCheckBox.Value;
            if app.CancerCheckBox.Value == 1
                app.color = 'rx'
                set(app.CancerCheckBox, 'Enable', 'off')
                set(app.CancerCheckBox, 'Enable', 'on')
            end 
        end

        % Button pushed function: SpectraLineGraphWhiteVsGrayvsCancerButton
        function SpectraLineGraphWhiteVsGrayvsCancerButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            app.file_name = file
            load(app.file_name,'qvals')
            figure(3); xlabel('q [1/A]'); ylabel('XRD amplitude [arb]'); title("Spectra Line Graph with Error Bars")

                    mrqa = mean(app.Cancerarray,1)
                    errred = std(app.Cancerarray)
                    figure(3); e1 = errorbar(qvals, mrqa, errred); 
                    e1.Color = 'r'
                    hold on;

                    mrqa2 = mean(app.whitearray,1)
                    errgreen = std(app.whitearray)
                    figure(3); e2 = errorbar(qvals, mrqa2, errgreen); 
                    e2.Color = 'g'

                    mrqa3 = mean(app.grayarray,1)
                    errblack = std(app.grayarray)
                    figure(3); e2 = errorbar(qvals, mrqa3, errblack); 
                    e2.Color = 'k'

                    legend('Cancer', 'White Matter', 'Gray Matter');
                    title("Spatial Locations");
               

        end

        % Button pushed function: LoadCoregButton
        function LoadCoregButtonPushed(app, event)
                       % string file_name 
            [file,path] = uigetfile('*.mat')

                if strcmp(file ,'a1.mat') == 1
                    load('a1.mat', 'a1')
                    imshow(a1)
                elseif strcmp(file,'b1.mat') == 1
                    load('b1.mat','a1')
                    imshow(a1)
                elseif strcmp(file,'c1.mat') == 1
                    load('c1.mat','c1')
                    imshow(c1)
                elseif strcmp(file,'d1.mat') == 1
                    load('d1.mat','d1')
                    imshow(d1)
                elseif strcmp(file,'e1.mat') == 1
                    load('e1.mat','e1')
                    imshow(e1)                 
                end 
        end

        % Value changed function: WhiteMatterCheckBox
        function WhiteMatterCheckBoxValueChanged(app, event)
            app.color = app.WhiteMatterCheckBox.Value;
            if app.WhiteMatterCheckBox.Value == 1
                app.color = 'gx'
                set(app.WhiteMatterCheckBox, 'Enable', 'off')
                set(app.WhiteMatterCheckBox, 'Enable', 'on')
            end 
        end

        % Value changed function: GrayMatterCheckBox
        function GrayMatterCheckBoxValueChanged(app, event)
           app.color = app.GrayMatterCheckBox.Value;
           if app.WhiteMatterCheckBox.Value == 1
                app.ncolor = 'kx'
                set(app.WhiteMatterCheckBox, 'Enable', 'off')
                set(app.WhiteMatterCheckBox, 'Enable', 'on')
            end 
        end

        % Value changed function: NoCancerCheckBox
        function NoCancerCheckBoxValueChanged(app, event)
            app.color = app.NoCancerCheckBox.Value;
            if app.CancerCheckBox.Value == 0
                app.ncolor = 'bx'
                set(app.CancerCheckBox, 'Enable', 'off')
                set(app.CancerCheckBox, 'Enable', 'on')
            end
        end

        % Button pushed function: ClusteringCancervsNonCancerButton
        function ClusteringCancervsNonCancerButtonPushed(app, event)
            import umapFileExchange.*;
            [reduction1] = run_umap(app.Cancerarray)
            [reduction2] = run_umap(app.NonCancerarray)

            figure(6); plot(reduction1(:,1), reduction1(:,2),'r.')
            hold on;
            figure(6); plot(reduction2(:,1), reduction2(:,2),'b.')
            legend('Cancer','Non-cacner' );
            title('Transpose')
        end

        % Button pushed function: SpectraLineGraphCancervsNonCancerButton
        function SpectraLineGraphCancervsNonCancerButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            app.file_name = file
            load(app.file_name,'qvals')
            figure(3); xlabel('q [1/A]'); ylabel('XRD amplitude [arb]'); title("Spectra Line Graph with Error Bars")

                    mrqa = mean(app.Cancerarray,1)
                    errred = std(app.Cancerarray)/sqrt(length(app.Cacnerarray))
                    figure(3); e1 = errorbar(qvals, mrqa, errred); 
                    e1.Color = 'r'
                    hold on;

                    mrqa1 = mean(app.NonCancerarray,1)
                    errblue = std(app.NonCancerarray)/sqrt(length(app.NonCancerarray))
                    e2 = errorbar(qvals, mrqa1, errblue); 
                    e2.Color = 'b'

                    legend('Cancer', 'Non-cacner');
                    title("Spatial Locations");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XRDdataButton = uibutton(app.UIFigure, 'push');
            app.XRDdataButton.ButtonPushedFcn = createCallbackFcn(app, @XRDdataButtonPushed, true);
            app.XRDdataButton.Text = 'XRD data';
            app.datapickerButton = uibutton(app.UIFigure, 'push');
            app.datapickerButton.ButtonPushedFcn = createCallbackFcn(app, @datapickerButtonPushed, true);
            app.datapickerButton.Text = 'datapicker';
            app.ClusteringWhitevsGrayvsCancerButton = uibutton(app.UIFigure, 'push');
            app.ClusteringWhitevsGrayvsCancerButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringWhitevsGrayvsCancerButtonPushed, true);
            app.ClusteringWhitevsGrayvsCancerButton.Text = 'Clustering White vs Gray vs Cancer ';
            app.spatiallocationplotButton = uibutton(app.UIFigure, 'push');
            app.spatiallocationplotButton.ButtonPushedFcn = createCallbackFcn(app, @spatiallocationplotButtonPushed, true);
            app.spatiallocationplotButton.Text = 'spatial location plot';
            app.CancerCheckBox = uicheckbox(app.UIFigure);
            app.CancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @CancerCheckBoxValueChanged, true);
            app.CancerCheckBox.Text = 'Cancer';
            app.SpectraLineGraphWhiteVsGrayvsCancerButton = uibutton(app.UIFigure, 'push');
            app.SpectraLineGraphWhiteVsGrayvsCancerButton.ButtonPushedFcn = createCallbackFcn(app, @SpectraLineGraphWhiteVsGrayvsCancerButtonPushed, true);
            app.SpectraLineGraphWhiteVsGrayvsCancerButton.Text = 'Spectra Line Graph White Vs Gray vs Cancer';
            app.LoadCoregButton = uibutton(app.UIFigure, 'push');
            app.LoadCoregButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoregButtonPushed, true);
            app.LoadCoregButton.Text = 'Load Coreg';
            app.WhiteMatterCheckBox = uicheckbox(app.UIFigure);
            app.WhiteMatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhiteMatterCheckBoxValueChanged, true);
            app.WhiteMatterCheckBox.Text = 'White Matter';
            app.GrayMatterCheckBox = uicheckbox(app.UIFigure);
            app.GrayMatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrayMatterCheckBoxValueChanged, true);
            app.GrayMatterCheckBox.Text = 'Gray Matter';
            app.NoCancerCheckBox = uicheckbox(app.UIFigure);
            app.NoCancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoCancerCheckBoxValueChanged, true);
            app.NoCancerCheckBox.Text = 'NoCancer';
            app.ClusteringCancervsNonCancerButton = uibutton(app.UIFigure, 'push');
            app.ClusteringCancervsNonCancerButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringCancervsNonCancerButtonPushed, true);
            app.ClusteringCancervsNonCancerButton.Text = 'Clustering Cancer vs NonCancer';
            app.SpectraLineGraphCancervsNonCancerButton = uibutton(app.UIFigure, 'push');
            app.SpectraLineGraphCancervsNonCancerButton.ButtonPushedFcn = createCallbackFcn(app, @SpectraLineGraphCancervsNonCancerButtonPushed, true);
            app.SpectraLineGraphCancervsNonCancerButton.Text = 'Spectra Line Graph Cancer vs NonCancer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coregapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end