classdef coregsection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        loadcoregButton               matlab.ui.control.Button
        CombinedCoregistrationButton  matlab.ui.control.Button
        GrayMatterBinaryMaskButton    matlab.ui.control.Button
        WhiteMatterBinaryMaskButton   matlab.ui.control.Button
        CancerBinaryMaskButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_name % Description
        cancerblock % Description
        whitematterblock % Description
        graymatterblock % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadcoregButton
        function loadcoregButtonPushed(app, event)
          % string file_name 
            [file,path] = uigetfile('*.mat')
           
                if strcmp(file ,'NT158.mat') == 1
                    load('NT158.mat', 'a')
                    imshow(a)
                elseif strcmp(file,'NT177.mat') == 1
                    load('NT177.mat','b')
                    imshow(b)
                elseif strcmp(file,'NT187.mat') == 1
                    load('NT187.mat','c')
                    imshow(c)
                elseif strcmp(file,'NT176.mat') == 1
                    load('NT176.mat','l')
                    imshow(l)
                elseif strcmp(file,'T1158.mat') == 1
                    load('T1158.mat','e')
                    imshow(e)
                elseif strcmp(file,'T1176.mat') == 1
                    load('T1176.mat','f')
                    imshow(f)
                elseif strcmp(file,'T1177.mat') == 1
                    load('T1177.mat','g')
                    imshow(g)
                elseif strcmp(file,'T1187.mat') == 1
                    load('T1187.mat','h')
                    imshow(h)
                elseif strcmp(file,'T2158.mat') == 1
                    load('T2158.mat','i')
                    imshow(i)
                elseif strcmp(file,'T2177.mat') == 1
                    load('T2177.mat','j')
                    imshow(j)
                elseif strcmp(file,'T2187.mat') == 1
                    load('T2187.mat','k')
                    imshow(k)
                elseif strcmp(file,'T2176.mat') == 1
                    load('T2176.mat','m')
                    imshow(m)
                elseif strcmp(file,'gbmt_crossed_mask.mat') == 1
                    load('gbmt_crossed_mask.mat','gbmt')
                    imshow(gbmt)
                end
        end

        % Button pushed function: CancerBinaryMaskButton
        function CancerBinaryMaskButtonPushed(app, event)
 
                random = drawfreehand;
                Amask = createMask(random);
                
                redChannel = 255 * uint8(Amask);
                greenChannel = 0 * ones(size(Amask), 'uint8'); 
                blueChannel = 0 * ones(size(Amask), 'uint8');
                app.cancerblock = cat(3, redChannel, greenChannel, blueChannel);
                %app.cancerblock = [app.cancerblock, app.cancerblock]
                figure (2); imshow(app.cancerblock)
                [r c] = find(app.cancerblock == 255)
                assignin('base','r',r);
                assignin('base','c',c);

        end

        % Button pushed function: WhiteMatterBinaryMaskButton
        function WhiteMatterBinaryMaskButtonPushed(app, event)
                random = drawfreehand;
                Bmask = createMask(random);

                blueChannel = 0 * uint8(Bmask);
                redChannel = 255 * ones(size(Bmask), 'uint8'); 
                greenChannel = 0 * ones(size(Bmask), 'uint8');
                app.whitematterblock = cat(3, redChannel, greenChannel, blueChannel);
                figure (3); imshow(app.whitematterblock)


        end

        % Button pushed function: GrayMatterBinaryMaskButton
        function GrayMatterBinaryMaskButtonPushed(app, event)
                random = drawfreehand;
                Cmask = createMask(random);

                greenChannel = 0 * uint8(Cmask);
                redChannel = 255 * ones(size(Cmask), 'uint8'); 
                blueChannel = 0 * ones(size(Cmask), 'uint8');
                app.graymatterblock  = cat(3, redChannel, greenChannel, blueChannel);
                figure (4); imshow(app.graymatterblock)

        end

        % Button pushed function: CombinedCoregistrationButton
        function CombinedCoregistrationButtonPushed(app, event)
            poop = [app.cancerblock + app.graymatterblock + app.whitematterblock]
            figure(8); imshow(poop)


            %subplot(1,2,2);
            %figure(11); imshow(app.cancerblock)
           % hold on;
            %subplot(1,2,2);
           % figure(11); imshow(app.graymatterblock)
            %hold on;
            %figure(11); imshow(app.whitematterblock)
           % hold on;

           %       a = imshow(app.cancerblock)
          %  hold on;
           % b = imshow(app.graymatterblock)
           % hold on;
           % c = imshow(app.whitematterblock)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancerBinaryMaskButton = uibutton(app.UIFigure, 'push');
            app.CancerBinaryMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CancerBinaryMaskButtonPushed, true);
            app.CancerBinaryMaskButton.Text = 'Cancer Binary Mask';
            app.WhiteMatterBinaryMaskButton = uibutton(app.UIFigure, 'push');
            app.WhiteMatterBinaryMaskButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteMatterBinaryMaskButtonPushed, true);
            app.WhiteMatterBinaryMaskButton.Text = 'White Matter Binary Mask';
            app.GrayMatterBinaryMaskButton = uibutton(app.UIFigure, 'push');
            app.GrayMatterBinaryMaskButton.ButtonPushedFcn = createCallbackFcn(app, @GrayMatterBinaryMaskButtonPushed, true);
            app.GrayMatterBinaryMaskButton.Text = 'Gray Matter Binary Mask';
            app.CombinedCoregistrationButton = uibutton(app.UIFigure, 'push');
            app.CombinedCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @CombinedCoregistrationButtonPushed, true);
            app.CombinedCoregistrationButton.Text = 'Combined Coregistration';
            app.loadcoregButton = uibutton(app.UIFigure, 'push');
            app.loadcoregButton.ButtonPushedFcn = createCallbackFcn(app, @loadcoregButtonPushed, true);
            app.loadcoregButton.Text = 'load coreg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coregsection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end