classdef datapicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DatapickerButton  matlab.ui.control.Button
        LoadDataButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % string file_name 
            [file,path] = uigetfile('*.mat')
           
                if strcmp(file ,'NT158.mat') == 1
                    load('NT158.mat', 'a')
                    imshow(a)
                elseif strcmp(file,'NT177.mat') == 1
                    load('NT177.mat','b')
                    imshow(b)
                elseif strcmp(file,'NT187.mat') == 1
                    load('NT187.mat','c')
                    imshow(c)
                elseif strcmp(file,'NT176.mat') == 1
                    load('NT176.mat','l')
                    imshow(l)
                elseif strcmp(file,'T1158.mat') == 1
                    load('T1158.mat','e')
                    imshow(e)
                elseif strcmp(file,'T1176.mat') == 1
                    load('T1176.mat','f')
                    imshow(f)
                elseif strcmp(file,'T1177.mat') == 1
                    load('T1177.mat','g')
                    imshow(g)
                elseif strcmp(file,'T1187.mat') == 1
                    load('T1187.mat','h')
                    imshow(h)
                elseif strcmp(file,'T2158.mat') == 1
                    load('T2158.mat','i')
                    imshow(i)
                elseif strcmp(file,'T2177.mat') == 1
                    load('T2177.mat','j')
                    imshow(j)
                elseif strcmp(file,'T2187.mat') == 1
                    load('T2187.mat','k')
                    imshow(k)
                elseif strcmp(file,'T2176.mat') == 1
                    load('T2176.mat','m')
                    imshow(m)
                end
        end

        % Button pushed function: DatapickerButton
        function DatapickerButtonPushed(app, event)
           [x,y] = getpts()  
           x = floor(x)
           y = floor(y)
           assignin('base','x',x);
           assignin('base','y',y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DatapickerButton = uibutton(app.UIFigure, 'push');
            app.DatapickerButton.ButtonPushedFcn = createCallbackFcn(app, @DatapickerButtonPushed, true);
            app.DatapickerButton.Text = 'Datapicker';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datapicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end