classdef dataslider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        lookatdataButton  matlab.ui.control.Button
        loaddataButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        x % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            %string file_name 
            [file,path] = uigetfile('*.mat')
           
                if strcmp(file ,'NT158.mat') == 1
                    load('NT158.mat', 'a')
                    imshow(a)
                elseif strcmp(file,'NT177.mat') == 1
                    load('NT177.mat','b')
                    imshow(b)
                elseif strcmp(file,'NT187.mat') == 1
                    load('NT187.mat','c')
                    imshow(c)
                elseif strcmp(file,'NT176.mat') == 1
                    load('NT176.mat','l')
                    imshow(l)
                elseif strcmp(file,'T1158.mat') == 1
                    load('T1158.mat','e')
                    imshow(e)
                elseif strcmp(file,'T1176.mat') == 1
                    load('T1176.mat','f')
                    imshow(f)
                elseif strcmp(file,'T1177.mat') == 1
                    load('T1177.mat','g')
                    imshow(g)
                elseif strcmp(file,'T1187.mat') == 1
                    load('T1187.mat','h')
                    imshow(h)
                elseif strcmp(file,'T2158.mat') == 1
                    load('T2158.mat','i')
                    imshow(i)
                elseif strcmp(file,'T2177.mat') == 1
                    load('T2177.mat','j')
                    imshow(j)
                elseif strcmp(file,'T2187.mat') == 1
                    load('T2187.mat','k')
                    imshow(k)
                elseif strcmp(file,'T2176.mat') == 1
                    load('T2176.mat','m')
                    imshow(m)
                end
        end

        % Button pushed function: lookatdataButton
        function lookatdataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            load(file, 'reconstructedData', 'qvals')
            % Enable data cursor mode
            datacursormode on
            dcm_obj = datacursormode(gcf);
            % Set update function
            set(dcm_obj,'UpdateFcn',@myupdatefcn)
            % Wait while the user to click
            %disp('Click line to display a data tip, then press "Return"')
            pause
            % Export cursor to workspace
            x = getCursorInfo(dcm_obj);
            figure(1); plot(qvals, squeeze(reconstructedData(x.Position(1,2),x.Position(1,1),:)))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loaddataButton = uibutton(app.UIFigure, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.lookatdataButton = uibutton(app.UIFigure, 'push');
            app.lookatdataButton.ButtonPushedFcn = createCallbackFcn(app, @lookatdataButtonPushed, true);
            app.lookatdataButton.Text = 'look at data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataslider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end