classdef umapreducupdate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NoCancerCheckBox     matlab.ui.control.CheckBox
        GrayMatterCheckBox   matlab.ui.control.CheckBox
        WhiteMatterCheckBox  matlab.ui.control.CheckBox
        CancerCheckBox       matlab.ui.control.CheckBox
        ClusteringButton     matlab.ui.control.Button
        DatapickerButton     matlab.ui.control.Button
        LoadCoregButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        file_name % Description
        Cancerarray % Description
        NonCancerarray % Description
        whitearray % Description
        grayarray % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCoregButton
        function LoadCoregButtonPushed(app, event)
   % string file_name 
            [file,path] = uigetfile('*.mat')

                if strcmp(file ,'a1.mat') == 1
                    load('a1.mat', 'a1')
                    imshow(a1)
                elseif strcmp(file,'b1.mat') == 1
                    load('b1.mat','a1')
                    imshow(a1)
                elseif strcmp(file,'c1.mat') == 1
                    load('c1.mat','c1')
                    imshow(c1)
                elseif strcmp(file,'d1.mat') == 1
                    load('d1.mat','d1')
                    imshow(d1)
                elseif strcmp(file,'e1.mat') == 1
                    load('e1.mat','e1')
                    imshow(e1)                 
                end 
        end

        % Button pushed function: DatapickerButton
        function DatapickerButtonPushed(app, event)
            [file,path] = uigetfile('*.mat')
            app.file_name = file
            load(app.file_name, 'reconstructedData')
            
                random = drawfreehand;
                tmask = createMask(random);
                figure (2); imshow(tmask)
                [r c] = find(tmask == 1)

                
%                app.n = 150
%                [r,c] = ginput(app.n)
               x = r
               y = c
               L = length(r)
            % Creating Clusters
            sqa = []
            for k1 = 1:L
                sqa = cat(2, sqa, squeeze(reconstructedData(x(k1),y(k1),:)))
            end

            if app.CancerCheckBox.Value == 1
                app.Cancerarray = transpose(sqa)
            elseif app.NoCancerCheckBox.Value == 1
                app.NonCancerarray = transpose(sqa)
            elseif app.WhiteMatterCheckBox.Value == 1
                app.whitearray = transpose(sqa)
            elseif app.GrayMatterCheckBox.Value == 1
                app.grayarray = transpose(sqa)
            end
        end

        % Button pushed function: ClusteringButton
        function ClusteringButtonPushed(app, event)
            rng('default')
            X = tsne(app.Cancerarray,'Algorithm','exact','Distance','euclidean');
            figure (1); gscatter(X(:,1),X(:,2),X,'brk')
            hold all;
            rng('default')
            Y = tsne(app.whitearray,'Algorithm','exact','Distance','euclidean');
            figure (1); gscatter(Y(:,1),Y(:,2),Y,'brk')
            hold all;
            Z = tsne(app.grayarray,'Algorithm','exact','Distance','euclidean');
            figure (1); gscatter(Z(:,1),Z(:,2),Z,'brk')
        end

        % Value changed function: CancerCheckBox
        function CancerCheckBoxValueChanged(app, event)
            value = app.CancerCheckBox.Value;
            
        end

        % Value changed function: NoCancerCheckBox
        function NoCancerCheckBoxValueChanged(app, event)
            value = app.NoCancerCheckBox.Value;
            
        end

        % Value changed function: WhiteMatterCheckBox
        function WhiteMatterCheckBoxValueChanged(app, event)
            value = app.WhiteMatterCheckBox.Value;
            
        end

        % Value changed function: GrayMatterCheckBox
        function GrayMatterCheckBoxValueChanged(app, event)
            value = app.GrayMatterCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadCoregButton = uibutton(app.UIFigure, 'push');
            app.LoadCoregButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoregButtonPushed, true);
            app.LoadCoregButton.Text = 'Load Coreg';
            app.DatapickerButton = uibutton(app.UIFigure, 'push');
            app.DatapickerButton.ButtonPushedFcn = createCallbackFcn(app, @DatapickerButtonPushed, true);
            app.DatapickerButton.Text = 'Datapicker';
            app.ClusteringButton = uibutton(app.UIFigure, 'push');
            app.ClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringButtonPushed, true);
            app.ClusteringButton.Text = 'Clustering ';
            app.CancerCheckBox = uicheckbox(app.UIFigure);
            app.CancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @CancerCheckBoxValueChanged, true);
            app.CancerCheckBox.Text = 'Cancer';
            app.WhiteMatterCheckBox = uicheckbox(app.UIFigure);
            app.WhiteMatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhiteMatterCheckBoxValueChanged, true);
            app.WhiteMatterCheckBox.Text = 'White Matter';
            app.GrayMatterCheckBox = uicheckbox(app.UIFigure);
            app.GrayMatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrayMatterCheckBoxValueChanged, true);
            app.GrayMatterCheckBox.Text = 'Gray Matter';
            app.NoCancerCheckBox = uicheckbox(app.UIFigure);
            app.NoCancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoCancerCheckBoxValueChanged, true);
            app.NoCancerCheckBox.Text = 'NoCancer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = umapreducupdate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end