classdef appconnect_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GreyMatterWhiteMatterCheckBox  matlab.ui.control.CheckBox
        CancerNoCancerCheckBox         matlab.ui.control.CheckBox
        YvalueEditField                matlab.ui.control.EditField
        YvalueEditFieldLabel           matlab.ui.control.Label
        importumapButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        reduction % Description
        cancerindex % Description
        color % Description
        dimension % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importumapButton
        function importumapButtonPushed(app, event)
            import umapFileExchange.*;
            
            % string file_name 
            [file,path] = uigetfile('*.mat')
            file_name = file

            if strcmp(file_name ,'reconGBMT.mat') == 1
                load('reconGBMT.mat', 'reconstructedData2')
                dataspec = reshape(reconstructedData2, 146*72, [])
            elseif strcmp(file_name,'reconNormal.mat') == 1
                load('reconNormal.mat', 'reconstructedData3')
                dataspec = reshape(reconstructedData3, 146*63, [])
            elseif strcmp(file_name,'recon.mat') == 1 
                load('recon.mat','reconstructedData1')
                dataspec = reshape(reconstructedData1, 146*49, [])
            end

            [app.reduction] = run_umap(dataspec)
            figure; plot(app.reduction(:,1), app.reduction(:,2),'.')
            hold on;
            set(app.importumapButton, 'Enable', 'off')
            set(app.importumapButton, 'Text', 'umap loaded ...')
        end

        % Value changed function: YvalueEditField
        function YvalueEditFieldValueChanged(app, event)
            value = app.YvalueEditField.Value
            value_converted = str2double(strsplit(value,',')); % converts spectra string into double 
            app.cancerindex = [value_converted]
            plot(app.reduction(app.cancerindex,1), app.reduction(app.cancerindex,2), app.color) % plots double onto UMAP reduction 
            hold on;
        end

        % Value changed function: CancerNoCancerCheckBox
        function CancerNoCancerCheckBoxValueChanged(app, event)
            app.color = app.CancerNoCancerCheckBox.Value;
            if app.CancerNoCancerCheckBox.Value == 1
                app.color = 'rx'
            elseif app.CancerNoCancerCheckBox.Value == 0
                app.color = 'gx'
            end 
        end

        % Value changed function: GreyMatterWhiteMatterCheckBox
        function GreyMatterWhiteMatterCheckBoxValueChanged(app, event)
            app.color = app.GreyMatterWhiteMatterCheckBox.Value;
            if app.GreyMatterWhiteMatterCheckBox == 1
               app.color = 'yx'
            elseif app.GreyMatterWhiteMatterCheckBox == 0
                   app.color = 'bx'
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.importumapButton = uibutton(app.UIFigure, 'push');
            app.importumapButton.ButtonPushedFcn = createCallbackFcn(app, @importumapButtonPushed, true);
            app.importumapButton.Text = 'import umap';
            app.YvalueEditFieldLabel = uilabel(app.UIFigure);
            app.YvalueEditFieldLabel.Text = 'Y-value';
            app.YvalueEditField = uieditfield(app.UIFigure, 'text');
            app.YvalueEditField.ValueChangedFcn = createCallbackFcn(app, @YvalueEditFieldValueChanged, true);
            app.CancerNoCancerCheckBox = uicheckbox(app.UIFigure);
            app.CancerNoCancerCheckBox.ValueChangedFcn = createCallbackFcn(app, @CancerNoCancerCheckBoxValueChanged, true);
            app.CancerNoCancerCheckBox.Text = 'Cancer/ No Cancer';
            app.GreyMatterWhiteMatterCheckBox = uicheckbox(app.UIFigure);
            app.GreyMatterWhiteMatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreyMatterWhiteMatterCheckBoxValueChanged, true);
            app.GreyMatterWhiteMatterCheckBox.Text = 'Grey Matter/ White Matter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appconnect_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end