classdef cancercoreg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CombinedCoregistrationButton  matlab.ui.control.Button
        CheckBox8                     matlab.ui.control.CheckBox
        CheckBox7                     matlab.ui.control.CheckBox
        CheckBox6                     matlab.ui.control.CheckBox
        CheckBox5                     matlab.ui.control.CheckBox
        CheckBox4                     matlab.ui.control.CheckBox
        CheckBox3                     matlab.ui.control.CheckBox
        CheckBox2                     matlab.ui.control.CheckBox
        CheckBox                      matlab.ui.control.CheckBox
        CancerBinaryMaskButton        matlab.ui.control.Button
        loadcoregButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        cancerblock % Description
        cancer1 % Description
        cancer2 % Description
        cancer3 % Description
        cancer4 % Description
        cancer5 % Description
        cancer6 % Description
        cancer7 % Description
        cancer8 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadcoregButton
        function loadcoregButtonPushed(app, event)
                            % string file_name 
            [file,path] = uigetfile('*.mat')
           
                if strcmp(file ,'a1.mat') == 1
                    load('a1.mat', 'a1')
                    imshow(a1)
                elseif strcmp(file,'b1.mat') == 1
                    load('b1.mat','a1')
                    imshow(a1)
                elseif strcmp(file,'c1.mat') == 1
                    load('c1.mat','c1')
                    imshow(c1)
                elseif strcmp(file,'d1.mat') == 1
                    load('d1.mat','d1')
                    imshow(d1)
                elseif strcmp(file,'e1.mat') == 1
                    load('e1.mat','e1')
                    imshow(e1)
                elseif strcmp(file,'gtThyroid.mat') == 1
                    load('gtThyroid.mat','gt')
                    imshow(gt)
                end  
        end

        % Button pushed function: CancerBinaryMaskButton
        function CancerBinaryMaskButtonPushed(app, event)
               random = drawfreehand;
                Amask = createMask(random);
                
                redChannel = 255 * uint8(Amask);
                greenChannel = 0 * ones(size(Amask), 'uint8'); 
                blueChannel = 0 * ones(size(Amask), 'uint8');
                app.cancerblock = cat(3, redChannel, greenChannel, blueChannel);
                figure (2); imshow(app.cancerblock)
                hold on;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value == 1
                app.cancer1 = app.cancerblock
            end 
        end

        % Value changed function: CheckBox2
        function CheckBox2ValueChanged(app, event)
            if app.CheckBox2.Value == 1
                app.cancer2 = app.cancerblock
            end 
        end

        % Value changed function: CheckBox3
        function CheckBox3ValueChanged(app, event)
            if app.CheckBox3.Value == 1
                app.cancer3 = app.cancerblock
            end 
        end

        % Value changed function: CheckBox4
        function CheckBox4ValueChanged(app, event)
            if app.CheckBox4.Value == 1
                app.cancer4 = app.cancerblock
            end 
        end

        % Value changed function: CheckBox5
        function CheckBox5ValueChanged(app, event)
            if app.CheckBox5.Value == 1
                app.cancer5 = app.cancerblock
            end 
        end

        % Value changed function: CheckBox6
        function CheckBox6ValueChanged(app, event)
            if app.CheckBox6.Value == 1
                app.cancer6 = app.cancerblock
            end 
        end

        % Value changed function: CheckBox7
        function CheckBox7ValueChanged(app, event)
            if app.CheckBox7.Value == 1
                app.cancer7 = app.cancerblock
            end 
        end

        % Value changed function: CheckBox8
        function CheckBox8ValueChanged(app, event)
            if app.CheckBox8.Value == 1
                app.cancer8 = app.cancerblock
            end 
        end

        % Button pushed function: CombinedCoregistrationButton
        function CombinedCoregistrationButtonPushed(app, event)
            figure(8); imshow(app.cancer1 + app.cancer2 + app.cancer3+app.cancer4+ app.cancer5+ app.cancer6+ app.cancer7+app.cancer8)
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadcoregButton = uibutton(app.UIFigure, 'push');
            app.loadcoregButton.ButtonPushedFcn = createCallbackFcn(app, @loadcoregButtonPushed, true);
            app.loadcoregButton.Text = 'load coreg';
            app.CancerBinaryMaskButton = uibutton(app.UIFigure, 'push');
            app.CancerBinaryMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CancerBinaryMaskButtonPushed, true);
            app.CancerBinaryMaskButton.Text = 'Cancer Binary Mask';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox2 = uicheckbox(app.UIFigure);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox2ValueChanged, true);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox3 = uicheckbox(app.UIFigure);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox3ValueChanged, true);
            app.CheckBox3.Text = 'Check Box3';
            app.CheckBox4 = uicheckbox(app.UIFigure);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox4ValueChanged, true);
            app.CheckBox4.Text = 'Check Box4';
            app.CheckBox5 = uicheckbox(app.UIFigure);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox5ValueChanged, true);
            app.CheckBox5.Text = 'Check Box5';
            app.CheckBox6 = uicheckbox(app.UIFigure);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox6ValueChanged, true);
            app.CheckBox6.Text = 'Check Box6';
            app.CheckBox7 = uicheckbox(app.UIFigure);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox7ValueChanged, true);
            app.CheckBox7.Text = 'Check Box7';
            app.CheckBox8 = uicheckbox(app.UIFigure);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @CheckBox8ValueChanged, true);
            app.CheckBox8.Text = 'Check Box8';
            app.CombinedCoregistrationButton = uibutton(app.UIFigure, 'push');
            app.CombinedCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @CombinedCoregistrationButtonPushed, true);
            app.CombinedCoregistrationButton.Text = 'Combined Coregistration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cancercoreg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end