classdef app7connect2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        YvalueEditField       matlab.ui.control.NumericEditField
        YvalueEditFieldLabel  matlab.ui.control.Label
        XValueEditField       matlab.ui.control.NumericEditField
        XValueEditFieldLabel  matlab.ui.control.Label
        importumapButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        reduction % reduction
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importumapButton
        function importumapButtonPushed(app, event)
            import umapFileExchange.*;
            load('recon.mat')
            dataspec = reshape(reconstructedDataCopy, 146*49, [])
            [app.reduction] = run_umap(dataspec)
            figure; plot(app.reduction(:,1), app.reduction(:,2),'.')
            hold all;
            set(app.importumapButton, 'Enable', 'off')
            set(app.importumapButton, 'Text', 'umap loaded ...')
        end

        % Value changed function: XValueEditField
        function XValueEditFieldValueChanged(app, event)
            value = app.XValueEditField
            
        end

        % Value changed function: YvalueEditField
        function YvalueEditFieldValueChanged(app, event)
            value = app.XValueEditField.Value;
            value1 = app.YvalueEditField.Value;
            plot(app.reduction(value,1), app.reduction(value1,1), 'rx')
            hold on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.importumapButton = uibutton(app.UIFigure, 'push');
            app.importumapButton.ButtonPushedFcn = createCallbackFcn(app, @importumapButtonPushed, true);
            app.importumapButton.Text = 'import umap';
            app.XValueEditFieldLabel = uilabel(app.UIFigure);
            app.XValueEditFieldLabel.Text = 'X-Value';
            app.XValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.XValueEditField.ValueChangedFcn = createCallbackFcn(app, @XValueEditFieldValueChanged, true);
            app.YvalueEditFieldLabel = uilabel(app.UIFigure);
            app.YvalueEditFieldLabel.Text = 'Y-value';
            app.YvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.YvalueEditField.ValueChangedFcn = createCallbackFcn(app, @YvalueEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app7connect2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end